classdef roboticzapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Activity1Button      matlab.ui.control.Button
        Activity2Button      matlab.ui.control.Button
        Activity3Button      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        TextArea             matlab.ui.control.TextArea
        GENERATEROBOTButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Activity1Button
        function Activity1ButtonPushed(app, event)


            robot = rigidBodyTree('DataFormat','column','MaxNumBodies',3);
            %%
            % Specify arm lengths for the robot arm.
            L1 = 0.3;
            L2 = 0.3;
            H = 0.3;
            %%
            % Add |'link1'| body with |'joint1'| joint.
            body = rigidBody('link1');
            joint = rigidBodyJoint('joint1', 'revolute');
            setFixedTransform(joint,trvec2tform([0 0 H]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'base');
            %%
            % Add |'link2'| body with |'joint2'| joint.
            body = rigidBody('link2');
            joint = rigidBodyJoint('joint2','revolute');
            setFixedTransform(joint, trvec2tform([L1,0,0]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'link1');
            %%
            body = rigidBody('link3');
            joint = rigidBodyJoint('joint3','prismatic');
            setFixedTransform(joint, trvec2tform([L2, 0, 0]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'link2');
            
            % Add |'tool'| end effector with |'fix1'| fixed joint.
            body = rigidBody('tool');
            joint = rigidBodyJoint('fix1','fixed');
            setFixedTransform(joint, trvec2tform([0, 0, -0.3]));
            body.Joint = joint;
            addBody(robot, body, 'link3');
            
          
            showdetails(robot)


            t = (0:0.2:10)'; % Time
            count = length(t);
            
            radius = 0.4;
            theta = t*((pi/2)/t(end));
            points = radius*[cos(theta) -sin(theta) zeros(size(theta))+0.1];
            trans_1 = trvec2tform(points(count,:))
          
            
            %%
            % Pre-allocate configuration solutions as a matrix |qs|.
            q0 = homeConfiguration(robot);
            ndof = length(q0);
            qs = zeros(count, ndof);
           
            ik = inverseKinematics('RigidBodyTree', robot);
            weights = [0, 0, 0, 1, 1, 1];
            endEffector = 'tool';


            qInitial = q0; % Use home configuration as the initial guess
            for i = 1:count
                % Solve for the configuration satisfying the desired end effector
                % position
                point = points(i,:);
                qSol = ik(endEffector,trvec2tform(point),weights,qInitial);
                % Store the configuration
                qs(i,:) = qSol;
                % Start from prior solution
                qInitial = qSol;
            end
            
           
            figure
            show(robot,qs(1,:)');
           
           
            hold on
            plot3(app.UIAxes,points(:,1),points(:,2),points(:,3),'k')


            framesPerSecond = 15;
            r = rateControl(framesPerSecond);
            for i = 1:count
                show(robot,qs(i,:)','PreservePlot',false);
                drawnow
                waitfor(r);
            end

           
            %% -----------------------------------------------------------------
        end

        % Button pushed function: Activity2Button
        function Activity2ButtonPushed(app, event)
            robot = rigidBodyTree('DataFormat','column','MaxNumBodies',3);
            %%
            % Specify arm lengths for the robot arm.
            L1 = 0.3;
            L2 = 0.3;
            H = 0.3;
            %%
            % Add |'link1'| body with |'joint1'| joint.
            body = rigidBody('link1');
            joint = rigidBodyJoint('joint1', 'revolute');
            setFixedTransform(joint,trvec2tform([0 0 H]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'base');
            %%
            % Add |'link2'| body with |'joint2'| joint.
            body = rigidBody('link2');
            joint = rigidBodyJoint('joint2','revolute');
            setFixedTransform(joint, trvec2tform([L1,0,0]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'link1');
            %%
            body = rigidBody('link3');
            joint = rigidBodyJoint('joint3','prismatic');
            setFixedTransform(joint, trvec2tform([L2, 0, 0]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'link2');
            
            % Add |'tool'| end effector with |'fix1'| fixed joint.
            body = rigidBody('tool');
            joint = rigidBodyJoint('fix1','fixed');
            setFixedTransform(joint, trvec2tform([0, 0, -0.3]));
            body.Joint = joint;
            addBody(robot, body, 'link3');
            
          
            showdetails(robot)


            t = (0:0.2:10)'; % Time
            count = length(t);
            
            radius = 0.4;
            theta = t*((3*pi/4)/t(end));
            theta0 = (3*pi/4) - theta;
            points1 = radius*[cos(theta) -sin(theta) zeros(size(theta))+0.1];
            trans1 = trvec2tform(points1(count,:))
            points2 = radius*[cos(theta0) -sin(theta0) zeros(size(theta0))+0.1];
            trans2 = trvec2tform(points2(count,:))
            points = cat(1,points1,points2);
            theta1 = t*((110*pi/180)/t(end)); 
            points3 = radius*[cos(theta1) sin(theta1) zeros(size(theta1))+0.1];
            trans3 = trvec2tform(points3(count,:))
            points = cat(1,points,points3);
            theta2 = (110*pi/180)-theta1;
            points4 = radius*[cos(theta2) sin(theta2) zeros(size(theta2))+0.1];
            trans4 = trvec2tform(points4(count,:))
            points = cat(1,points,points4);
            %%
            % Pre-allocate configuration solutions as a matrix |qs|.
            q0 = homeConfiguration(robot);
            ndof = length(q0);
            qs = zeros(count*4, ndof);
           
            ik = inverseKinematics('RigidBodyTree', robot);
            weights = [0, 0, 0, 1, 1, 1];
            endEffector = 'tool';


            qInitial = q0; % Use home configuration as the initial guess
            for i = 1:count*4
                % Solve for the configuration satisfying the desired end effector
                % position
                point = points(i,:);
                qSol = ik(endEffector,trvec2tform(point),weights,qInitial);
                % Store the configuration
                qs(i,:) = qSol;
                % Start from prior solution
                qInitial = qSol;
            end
            
           
            figure
            show(robot,qs(1,:)');
           
           
            hold on
            plot3(app.UIAxes,points(:,1),points(:,2),points(:,3),'k')


            framesPerSecond = 15;
            r = rateControl(framesPerSecond);
            for i = 1:(count*4)
                show(robot,qs(i,:)','PreservePlot',false);
                drawnow
                waitfor(r);
            end

            
        end

        % Button pushed function: Activity3Button
        function Activity3ButtonPushed(app, event)
            robot = rigidBodyTree('DataFormat','column','MaxNumBodies',3);
            %%
            % Specify arm lengths for the robot arm.
            L1 = 0.3;
            L2 = 0.3;
            H = 0.3;
            %%
            % Add |'link1'| body with |'joint1'| joint.
            body = rigidBody('link1');
            joint = rigidBodyJoint('joint1', 'revolute');
            setFixedTransform(joint,trvec2tform([0 0 H]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'base');
            %%
            % Add |'link2'| body with |'joint2'| joint.
            body = rigidBody('link2');
            joint = rigidBodyJoint('joint2','revolute');
            setFixedTransform(joint, trvec2tform([L1,0,0]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'link1');
            %%
            body = rigidBody('link3');
            joint = rigidBodyJoint('joint3','prismatic');
            setFixedTransform(joint, trvec2tform([L2, 0, 0]));
            joint.JointAxis = [0 0 1];
            body.Joint = joint;
            addBody(robot, body, 'link2');
            
            % Add |'tool'| end effector with |'fix1'| fixed joint.
            body = rigidBody('tool');
            joint = rigidBodyJoint('fix1','fixed');
            setFixedTransform(joint, trvec2tform([0, 0, -0.3]));
            body.Joint = joint;
            addBody(robot, body, 'link3');


            t = (0:0.2:10)'; % Time
            count = length(t);
            
            radius = 0.4;
            theta = t*((pi/2)/t(end));
            theta0 = (pi/2) - theta;
            box1 = [0.02 0 0]
            box2 = [0 0.02 0]
            box3 = [-0.02 0 0]
            box4 = [0 -0.02 0]
            % path 1
            points1 = box1 + radius*[cos(theta) -sin(theta) zeros(size(theta))];
            trans1 = trvec2tform(points1(count,:))
            points2 = box1 + radius*[cos(theta0) -sin(theta0) zeros(size(theta0))];
            trans2 = trvec2tform(points2(count,:))
            points = cat(1,points1,points2);
            theta1 = t*((pi/2)/t(end)); 
            theta2 = (pi/2)-theta1;
            % path 2
            points3 = box2 + radius*[cos(theta1) -sin(theta1) zeros(size(theta1))];
            trans3 = trvec2tform(points3(count,:))
            points4 = box2 + radius*[cos(theta2) -sin(theta2) zeros(size(theta2))];
            trans4 = trvec2tform(points4(count,:))
            points5 = box3 + radius*[cos(theta1) -sin(theta1) zeros(size(theta1))];
            trans5 = trvec2tform(points5(count,:))
            points6 = box3 + radius*[cos(theta2) -sin(theta2) zeros(size(theta2))];
            trans6 = trvec2tform(points6(count,:))
            
            points7 = box4 + radius*[cos(theta1) -sin(theta1) zeros(size(theta1))];
            trans7 = trvec2tform(points7(count,:))
            points8 = box4 + radius*[cos(theta2) -sin(theta2) zeros(size(theta2))];
            trans8 = trvec2tform(points8(count,:))
            
            pointsf = radius*[cos(theta1) -sin(theta1) zeros(size(theta1))];
            transf = trvec2tform(pointsf(count,:))


            points = cat(1,points,points3);
            points = cat(1,points,points4);
            points = cat(1,points,points5);
            points = cat(1,points,points6);
            points = cat(1,points,points7);
            points = cat(1,points,points8);
            points = cat(1,points,pointsf);
            
            
            %%
            % Pre-allocate configuration solutions as a matrix |qs|.
            q0 = homeConfiguration(robot);
            ndof = length(q0);
            qs = zeros(count*9, ndof);
           
            ik = inverseKinematics('RigidBodyTree', robot);
            weights = [0, 0, 0, 1, 1, 1];
            endEffector = 'tool';


            qInitial = q0; % Use home configuration as the initial guess
            for i = 1:count*9
                % Solve for the configuration satisfying the desired end effector
                % position
                point = points(i,:);
                qSol = ik(endEffector,trvec2tform(point),weights,qInitial);
                % Store the configuration
                qs(i,:) = qSol;
                % Start from prior solution
                qInitial = qSol;
            end
            
           
            figure
            show(robot,qs(1,:)');
           
           
            hold on
            plot3(points(:,1),points(:,2),points(:,3),'k')


            framesPerSecond = 15;
            r = rateControl(framesPerSecond);
            for i = 1:(count*9)
                show(robot,qs(i,:)','PreservePlot',false);
                drawnow
                waitfor(r);
            end

            
        end

        % Button pushed function: GENERATEROBOTButton
        function GENERATEROBOTButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Activity1Button = uibutton(app.UIFigure, 'push');
            app.Activity1Button.ButtonPushedFcn = createCallbackFcn(app, @Activity1ButtonPushed, true);
            app.Activity1Button.Text = 'Activity 1';
            app.Activity2Button = uibutton(app.UIFigure, 'push');
            app.Activity2Button.ButtonPushedFcn = createCallbackFcn(app, @Activity2ButtonPushed, true);
            app.Activity2Button.Text = 'Activity 2';
            app.Activity3Button = uibutton(app.UIFigure, 'push');
            app.Activity3Button.ButtonPushedFcn = createCallbackFcn(app, @Activity3ButtonPushed, true);
            app.Activity3Button.Text = 'Activity 3';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Click below, then select the activity you wish to perform:'; ''; 'Activity 1, pick and place 90 degrees'; ''; 'Activity 2, pick and place 2 items'; ''; 'Activity 3, unpack pallet'; ''};
            app.GENERATEROBOTButton = uibutton(app.UIFigure, 'push');
            app.GENERATEROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEROBOTButtonPushed, true);
            app.GENERATEROBOTButton.Text = 'GENERATE ROBOT ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roboticzapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end